classdef covidGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheVisualizationofCOVID19RelatedDataUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryLabel               matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateRegionProvinceLabel   matlab.ui.control.Label
        StateListBox               matlab.ui.control.ListBox
        DataofPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel  matlab.ui.control.Label
        AverageSlider              matlab.ui.control.Slider
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
    end

    
properties (Access = private)
        CountryList Country
        CountryNamesCell
        TimeLine
        SelectedCountry
        SelectedState
        WindowSize      % Formerly DayAverage
        PlotType        % Formerly Type
        DataMode        % Formerly Option
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            % Renamed from PlotCurves
            % Define colors
            col_bar = [0 0 1]; % Blue
            col_line = [1 0 0]; % Red
            
            % Localize properties for readable code
            wSize = app.WindowSize;
            pType = app.PlotType;
            sCountry = app.SelectedCountry;
            sState = app.SelectedState;
            dMode = app.DataMode;
            
            % Update Title
            titleType = pType;
            if strcmp(pType, 'Both')
                titleType = 'Cases and Deaths';
            end
            
            titleStr = sprintf('%s Numbers of %s in %s State(Province) of %s (%d-day mean)', ...
                dMode, titleType, sState, sCountry, wSize);
            app.UIAxes.Title.String = titleStr;
            
            % --- LOGIC REFACTOR: Removed bin2dec magic numbers ---
            
            % 1. Determine the raw data source
            rawCases = [];
            rawDeaths = [];
            
            isAllCountries = strcmp(sCountry, 'All Countries');
            
            if isAllCountries
                % Aggregating Global Data
                % We need to sum up the vectors from all country objects
                % Pre-extract all matrices to sum them
                all_DailyCases = vertcat(app.CountryList.DailyCases);
                all_DailyDeaths = vertcat(app.CountryList.DailyDeaths);
                all_CumCases = vertcat(app.CountryList.CumulativeCases);
                all_CumDeaths = vertcat(app.CountryList.CumulativeDeaths);
                
                if strcmp(dMode, 'Daily')
                    rawCases = sum(all_DailyCases, 1);
                    rawDeaths = sum(all_DailyDeaths, 1);
                else % Cumulative
                    rawCases = sum(all_CumCases, 1);
                    rawDeaths = sum(all_CumDeaths, 1);
                end
                
            else
                % Specific Country Selected
                idxC = strcmp(app.CountryNamesCell, sCountry);
                targetCountryObj = app.CountryList(idxC);
                
                isAllStates = strcmp(sState, 'all');
                
                if isAllStates
                    % Country Level Data
                    if strcmp(dMode, 'Daily')
                        rawCases = targetCountryObj.DailyCases;
                        rawDeaths = targetCountryObj.DailyDeaths;
                    else
                        rawCases = targetCountryObj.CumulativeCases;
                        rawDeaths = targetCountryObj.CumulativeDeaths;
                    end
                else
                    % Specific State Level Data
                    idxS = strcmp(targetCountryObj.StateNameList, sState);
                    targetStateObj = targetCountryObj.States(idxS);
                    
                    if strcmp(dMode, 'Daily')
                        rawCases = targetStateObj.DailyCases;
                        rawDeaths = targetStateObj.DailyDeaths;
                    else
                        rawCases = targetStateObj.CumulativeCases;
                        rawDeaths = targetStateObj.CumulativeDeaths;
                    end
                end
            end
            
            % 2. Apply Moving Average
            % Note: Check if data exists before smoothing
            finalCases = [];
            finalDeaths = [];
            
            % We only process the data if the user wants to plot it
            wantsCases = contains(pType, 'Cases') || strcmp(pType, 'Both');
            wantsDeaths = contains(pType, 'Deaths') || strcmp(pType, 'Both');
            
            if wantsCases && ~isempty(rawCases)
                finalCases = movmean(rawCases, wSize, 'Endpoints', 'discard');
            end
            
            if wantsDeaths && ~isempty(rawDeaths)
                finalDeaths = movmean(rawDeaths, wSize, 'Endpoints', 'discard');
            end

            % 3. Plotting Logic
            % Clear Axes first to avoid artifacts
            cla(app.UIAxes);
            yyaxis(app.UIAxes, 'left'); cla(app.UIAxes);
            yyaxis(app.UIAxes, 'right'); cla(app.UIAxes);
            
            % Setup Left Axis (Bars)
            if ~isempty(finalCases)
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, finalCases, 0.5, 'FaceColor', col_bar);
                app.UIAxes.YColor = col_bar;
                app.UIAxes.XLim = [1, length(finalCases)];
            else
                % Reset left axis if not used
                yyaxis(app.UIAxes, 'left');
                app.UIAxes.YColor = [0 0 0]; 
            end
            
            % Setup Right Axis (Lines)
            if ~isempty(finalDeaths)
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, finalDeaths, 'LineWidth', 2, 'Color', col_line);
                app.UIAxes.YColor = col_line;
                % Ensure XLim matches in case only deaths are plotted
                if isempty(finalCases)
                    app.UIAxes.XLim = [1, length(finalDeaths)];
                end
            else
                 % Reset right axis if not used
                 yyaxis(app.UIAxes, 'right');
                 app.UIAxes.YColor = [0 0 0];
            end
            
            % Common Axis properties
            app.UIAxes.YLim = [0, inf]; % Auto scale Y
            
            % X-Ticks Management
            lenTicks = length(app.UIAxes.XTick);
            lenDate = length(app.TimeLine);
            offset = ceil((wSize-1)/2);
            
            % Robust indexing for dates
            indices = 1+offset : ceil(lenDate/lenTicks) : lenDate-offset;
            if ~isempty(indices)
                app.UIAxes.XTickLabel = app.TimeLine(indices);
            end
            
            app.UIAxes.XTickLabelRotation = 60;
            app.UIAxes.FontWeight = 'bold';
        end        
    end

    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
% Load raw struct
            loadedStruct = load("covid_data.mat");
            rawData = loadedStruct.covid_data;
            
            % Initialize Date Timeline (Header row)
            app.TimeLine = rawData(1, 3:end);
            
            % Get Unique Country Names
            uniqueCountries = unique(rawData(2:end, 1));
            app.CountryNamesCell = uniqueCountries;
            
            % Instantiate Country Objects
            numCountries = length(uniqueCountries);
            
            % Preallocation loop
            for k = 1:numCountries
                cName = uniqueCountries{k};
                % Find rows belonging to this country
                mask = strcmp(cName, rawData(:, 1));
                
                % Call Constructor: Name, StateCol, DataCols
                stateCol = rawData(mask, 2);
                dataCols = rawData(mask, 3:end);
                
                app.CountryList(k) = Country(cName, stateCol, dataCols);
            end
            
            % Setup UI Initials
            app.CountryListBox.Items = [{'All Countries'}; uniqueCountries];
            app.SelectedCountry = 'All Countries';
            app.CountryListBox.Value = 'All Countries';
            
            app.StateListBox.Items = {'all'};
            app.SelectedState = 'all';
            
            app.PlotType = 'Cases';
            app.DataMode = 'Cumulative';
            app.WindowSize = 1;
            
            % Initial Plot
            updatePlot(app);
        end

        % Value changing function: AverageSlider
        function AverageSliderValueChanging(app, event)
            app.WindowSize = round(event.Value);
            updatePlot(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.SelectedCountry = app.CountryListBox.Value;
            
            % Update State List based on selection
            if strcmp(app.SelectedCountry, 'All Countries')
                 app.StateListBox.Items = {'all'};
            else
                idx = strcmp(app.CountryNamesCell, app.SelectedCountry);
                cObj = app.CountryList(idx);
                
                % Concatenate 'all' with the dynamic list from object
                app.StateListBox.Items = [{'all'}; cObj.StateNameList];
            end
            
            % Reset state to all when country changes
            app.SelectedState = 'all';
            app.StateListBox.Value = 'all';
            
            updatePlot(app);
        end

        % Selection changed function: DataofPlotButtonGroup
        function DataofPlotButtonGroupSelectionChanged(app, event)
            app.PlotType = app.DataofPlotButtonGroup.SelectedObject.Text;
            updatePlot(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.DataMode = app.OptionButtonGroup.SelectedObject.Text;
            updatePlot(app);
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            app.SelectedState = app.StateListBox.Value;
            updatePlot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TheVisualizationofCOVID19RelatedDataUIFigure = uifigure;
            app.TheVisualizationofCOVID19RelatedDataUIFigure.Name = 'The Visualization of COVID-19 Related Data';
            app.UIAxes = uiaxes(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            title(app.UIAxes, 'Daily Numbers of Cases and Deaths in China (7-day mean)')
            app.CountryLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.CountryLabel.Text = 'Country: ';
            app.CountryListBox = uilistbox(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.CountryListBox.Items = {'Empty'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'Empty';
            app.StateRegionProvinceLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.StateRegionProvinceLabel.Text = 'State\Region\Province: ';
            app.StateListBox = uilistbox(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.StateListBox.Items = {'Empty'};
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.StateListBox.Value = 'Empty';
            app.DataofPlotButtonGroup = uibuttongroup(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.DataofPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofPlotButtonGroupSelectionChanged, true);
            app.DataofPlotButtonGroup.Title = 'Data of  Plot';
            app.CasesButton = uiradiobutton(app.DataofPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DataofPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DataofPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.AveragedofdaysSliderLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AverageSlider = uislider(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.AverageSlider.Limits = [1 15];
            app.AverageSlider.ValueChangingFcn = createCallbackFcn(app, @AverageSliderValueChanging, true);
            app.AverageSlider.MinorTicks = [];
            app.AverageSlider.Value = 1;
            app.OptionButtonGroup = uibuttongroup(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
        end
    end

    methods (Access = public)

        % Construct app
        function app = covidGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheVisualizationofCOVID19RelatedDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheVisualizationofCOVID19RelatedDataUIFigure)
        end
    end
end